classdef FSK < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_2                        matlab.ui.control.Label
        BACKButton                     matlab.ui.control.Button
        FSKLabel                       matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        FunctionEditField              matlab.ui.control.EditField
        FunctionEditFieldLabel         matlab.ui.control.Label
        EnterfunctionastfunctionLabel  matlab.ui.control.Label
        SamplingperiodEditField        matlab.ui.control.NumericEditField
        SamplingperiodEditFieldLabel   matlab.ui.control.Label
        EndingtimeEditField            matlab.ui.control.NumericEditField
        EndingtimeEditFieldLabel       matlab.ui.control.Label
        StartingtimeEditField          matlab.ui.control.NumericEditField
        StartingtimeEditFieldLabel     matlab.ui.control.Label
        GraphDropDown                  matlab.ui.control.DropDown
        GraphDropDownLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
      %% part 1 : Input function and Sampling
            value=app.GraphDropDown.Value;
            ax0=app.UIAxes;
            app.UIAxes.FontSize=20;
            %input
            s=app.StartingtimeEditField.Value;
            e=app.EndingtimeEditField.Value;
            p=app.SamplingperiodEditField.Value;
            f=str2func(app.FunctionEditField.Value);
            %sampling
            t=s:p:e;
            y=f(t);
            %Nyquist theorem
            temp=(p/100);
            tempt=0:0.1:20*2*pi;
            tempy=f(tempt);
            ac=xcorr(tempy,tempy);
            [~,locs]=findpeaks(ac);
            d=mean(diff(locs)*0.1)/2;
            if(p>d)
                app.Label.Text=strcat('Warning :Sampling period should be less than',num2str(d));
                app.Label.FontColor='r';
                return;
           else
            app.Label.Text='';
            end
            %
            if strcmp(value,'Actual Signal')
                plot(app.UIAxes,t,y);
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='displacement';
                app.UIAxes.Title.String='Actual Signal';
                app.UIAxes.XLim=[s e];
                app.UIAxes.YLim=[min(y) max(y)];
                app.Label_2.Text='';
            end
            if strcmp(value,'Actual Signal')
                return
            end
            %% Part2: Analogue to binary signal 
            % Quantization (4 bits)
            b=4;
            vmax=max(y);
            vmin=min(y);
            del=(vmax-vmin)/(2^b-1);
            lev=[vmin:del:vmax];
            
            for i=1:length(y)
                for j=1:length(lev)
                    if abs(y(i)-lev(j))<del/2
                    xq(i)=lev(j);        %quantization level
                    xd(i)=j-1;            %index of level                      
                    break;
                    end
                end
            end
                %% binary siganl
            xdig=de2bi(xd,'left-msb');       
            dig=[];
            for i=1:length(xdig)                %digital value
                dig=[dig xdig(i,:)];           
            end
            if strcmp(value,'Digitized Signal')
                stem(app.UIAxes,dig);axis tight  %discrete signal plot
                app.UIAxes.XLabel.String='n';
                app.UIAxes.YLabel.String='displacement';
                app.UIAxes.Title.String='Digitized Signal';
                app.UIAxes.XLim=[0 length(dig)];
                app.UIAxes.YLim=[-0.2 1.2];
                app.Label_2.Text='';
                return;
            end           
            %% FSK
            n=length(dig);
            m=dig;                                  %copying the dig array to m
            Tb=1;
            fc1=1000;
            fc2=2;
            t=[0:0.01:Tb];                        
            c1= sqrt(2/Tb)*sin(2*pi*fc1*t);          %carrier signal 1 (1000hz)
            c2= sqrt(2/Tb)*sin(2*pi*fc2*t);          %carrier signal 2 (2 hz)
             t1=0;
            t2=Tb;
            for i=1:length(dig)
                t=[t1:0.01: t2];
                if m(i)>0.5
                    m(i)=1;
                    m_s=ones(1, length(t));
                else
                    m(i)=0;
                    m_s=zeros(1,length(t));          
                end
                message (i,:)=m_s;                  %tidy 
                if m(i)==1
                    fsk_signal (i,:)=c1;           
                else
                    fsk_signal (i,:)=c2;
                end        
                t1=t1+Tb;
                t2=t2+Tb;
                if strcmp(value,'Message Signal')
                plot(app.UIAxes,t,message(i,:),'b');
                hold(ax0,'on');axis tight;  %discrete signal plot
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='m(t)';
                app.UIAxes.Title.String='Digital Message Signal of the Binary Bits';
                app.UIAxes.YLim=[-2 2];
                app.UIAxes.XLim=[0 n];
                app.Label_2.Text='';
                end    
                if strcmp(value,'FSK Modulated Signal')
                plot(app.UIAxes,t,fsk_signal(i,:),'b');
                hold(ax0,'on');axis tight;  %discrete signal plot
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='a(t)';
                app.UIAxes.Title.String='FSK Modulation of the Digital Message Signal';
                %app.UIAxes.YLim=[-2 2];
                app.UIAxes.XLim=[0 n];
                app.Label_2.Text='';
                end    
            end
            hold(ax0,'off');
            if strcmp(value,'Message Signal') || strcmp(value,'FSK Modulated Signal')
                return
            end
             %% noise
            t1=0;
            t2=Tb;
            for i=1:length(dig)
                fsk_signal(i,:)=awgn(fsk_signal(i,:),5);   %signal to noise ratio=5
                t=[t1:0.01:t2];
                if strcmp(value,'FSK Modulated Signal With Noise')
                plot(app.UIAxes,t,fsk_signal(i,:),'b');axis tight;  %discrete signal plot
                hold(ax0,'on');
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='a(t) with noise';
                app.UIAxes.Title.String='FSK Modulation Signal With Noise';
                app.UIAxes.XLim=[0 n];
                app.UIAxes.YLim=[-5 5];
                app.Label_2.Text='';
                end    
                t1=t1+Tb;
                t2=t2+Tb;
            end
            hold(ax0,'off');
            if strcmp(value,'FSK Modulated Signal With Noise')
                return
            end
            %% part 4:  (FSK demodulation)
            %rectified signal
            t1=0;
            t2=Tb;
            for i=1:length(dig)
                t=[t1:0.01:t2];
                for j=1:length(t)
                    if fsk_signal (i,j)>0
                        rect(i,j)=fsk_signal(i,j);
                    else 
                        rect(i,j)=0;
                    end
                end
                t1=t1+Tb;
                t2=t2+Tb;
                if strcmp(value,'Rectified Signal')
                plot(app.UIAxes,t,rect(i,:),'b');axis tight;  %discrete signal plot
                hold(ax0,'on');
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='r(t)';
                app.UIAxes.Title.String='Rectified Signal';
                app.UIAxes.YLim=[0 5];
                app.UIAxes.XLim=[0 n];
                app.Label_2.Text='';
                end    
            end
            hold(ax0,'off');
             if strcmp(value,'Rectified Signal')
                return;
             end
             %% smoothing
            t1=0;
            t2=Tb;
            for i=1:length(dig)
                t=[t1:0.01:t2];
                smoothed (i,:)=smooth(rect(i,:),500);  % smoothing function
            t1=t1+Tb;
            t2=t2+Tb;
            if strcmp(value,'Smooth Signal')
                plot(app.UIAxes,t,smoothed(i,:),'b');axis tight;  %discrete signal plot
                hold(ax0,'on');
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='s(t)';
                app.UIAxes.Title.String='Smooth Signal';
                app.UIAxes.YLim=[-0.2 3];
                app.UIAxes.XLim=[0 n];
                app.Label_2.Text='';
            end
            end
            hold(ax0,'off');
            if strcmp(value,'Smooth Signal')
                return;
            end
                        %% Demodulated binary data
            t1=0;
            t2=Tb;
            for i=1:length(dig)
                t=[t1:.01:t2];                              
                decision_val=sum( smoothed(i,:))/length(t);      %average value 
                average(i,:)=decision_val; 
                t1=t1+Tb;
                t2=t2+Tb;
            end
            max_avg= max(average);
            min_avg=min(average);
            avg=((max_avg)+(min_avg))/2;
            for i=1:length(dig)
               if average(i,:) < avg
                    x(i)=0;
               else
                   x(i)=1;
               end
            end
            if strcmp(value,'FSK Demodulated Signal')
                stem(app.UIAxes,x,'b');hold on;axis tight;  %discrete signal plot
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='x(n)';
                app.UIAxes.Title.String='FSK Demodulated Binary Data(decoded signal)';
                app.UIAxes.YLim=[min(x)-0.2 max(x)+0.2];
                app.UIAxes.XLim=[0 n];
                app.Label_2.Text='';
            end
            hold(ax0,'off');
            if strcmp(value,'FSK Demodulated Signal')
                return;
            end
             %% bit error ratio
            E=x-dig;
            BER=0;
            for i=1:length(y)
                if E(i)~=0
                    BER=BER+1;     %bit error ratio
                end
            end
            BE_Ratio=BER/length(E);
                        %% part 5 (decoding)
            % binary to decimal
            b=4;
            j=1;
            samp=length(x)/b;
            for i=1:b:length(x)
                dig_row(j,:)=x(1,i:i+3);
                j=j+1;
            end
            for i=1:size(dig_row,1)
                lev(i)=bi2de(dig_row(i,:),'left-msb');
            end
            
            %%  reconstracted signal
            levvalue=lev*del+min(y);
            if strcmp(value,'Decoded Quantized Signal')
                stem(app.UIAxes,levvalue);axis tight;  %discrete signal plot
                app.UIAxes.XLabel.String='Index';
                app.UIAxes.YLabel.String='Displacement';
                app.UIAxes.Title.String='Decoded Quantized Signal';
                app.UIAxes.XLim=[0 n/4];
                app.UIAxes.YLim=[min(levvalue)-0.2 max(levvalue)+0.2];
                app.Label_2.Text=strcat('Bit error ratio: ',num2str(BE_Ratio));
                return;
            end
            td=linspace(0,10,length(levvalue));
            t=0:0.001:10;
            xre=interp1(td,levvalue,t,'spline');
           if strcmp(value,'Reconstructed Signal')
                plot(app.UIAxes,t,xre);axis tight;  %discrete signal plot
                app.UIAxes.XLabel.String='time';
                app.UIAxes.YLabel.String='Displacement';
                app.UIAxes.Title.String='Reconstructed Signal';
                app.UIAxes.XLim=[0 10];
                app.UIAxes.YLim=[min(levvalue)-0.2 max(levvalue)+0.2];
                app.Label_2.Text=strcat('Bit error ratio: ',num2str(BE_Ratio));
                return;
           end 
        end

        % Button pushed function: BACKButton
        function BACKButtonPushed(app, event)
            AskFsk;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.Items = {'Actual Signal', 'Digitized Signal', 'Message Signal', 'FSK Modulated Signal', 'FSK Modulated Signal With Noise', 'Rectified Signal', 'Smooth Signal', 'FSK Demodulated Signal', 'Decoded Quantized Signal', 'Reconstructed Signal'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = 'Actual Signal';
            app.StartingtimeEditFieldLabel = uilabel(app.UIFigure);
            app.StartingtimeEditFieldLabel.Text = 'Starting time';
            app.StartingtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndingtimeEditFieldLabel = uilabel(app.UIFigure);
            app.EndingtimeEditFieldLabel.Text = 'Ending time';
            app.EndingtimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingperiodEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingperiodEditFieldLabel.Text = 'Sampling period';
            app.SamplingperiodEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnterfunctionastfunctionLabel = uilabel(app.UIFigure);
            app.EnterfunctionastfunctionLabel.Text = 'Enter function as: @(t) function';
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FSKLabel = uilabel(app.UIFigure);
            app.FSKLabel.Text = 'FSK';
            app.BACKButton = uibutton(app.UIFigure, 'push');
            app.BACKButton.ButtonPushedFcn = createCallbackFcn(app, @BACKButtonPushed, true);
            app.BACKButton.Text = 'BACK';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSK

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end